classdef PageScanParameterTunerApp < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        ParameterTunerUIFigure          matlab.ui.Figure
        FileMenu                        matlab.ui.container.Menu
        LoadPageMenu                    matlab.ui.container.Menu
        EditMenu                        matlab.ui.container.Menu
        ZoomMenu                        matlab.ui.container.Menu
        PanMenu                         matlab.ui.container.Menu
        ResetZoomPanMenu                matlab.ui.container.Menu
        UIAxes                          matlab.ui.control.UIAxes
        ParametersPanel                 matlab.ui.container.Panel
        MinCharWidthSpinnerLabel        matlab.ui.control.Label
        MinCharWidthSpinner             matlab.ui.control.Spinner
        MaxCharWidthSpinnerLabel        matlab.ui.control.Label
        MaxCharWidthSpinner             matlab.ui.control.Spinner
        MinCharHeightSpinnerLabel       matlab.ui.control.Label
        MinCharHeightSpinner            matlab.ui.control.Spinner
        MaxCharHeightSpinnerLabel       matlab.ui.control.Label
        MaxCharHeightSpinner            matlab.ui.control.Spinner
        ShortCharThesholdSpinnerLabel   matlab.ui.control.Label
        ShortHeightThresholdSpinner     matlab.ui.control.Spinner
        ColumnDistThesholdSpinnerLabel  matlab.ui.control.Label
        ColumnDistThresholdSpinner      matlab.ui.control.Spinner
        HorizontalDilationSpinnerLabel  matlab.ui.control.Label
        HorizontalDilationSpinner       matlab.ui.control.Spinner
        VerticalDilationSpinnerLabel    matlab.ui.control.Label
        VerticalDilationSpinner         matlab.ui.control.Spinner
    end

    
    properties (Access = private)
    end
    
    properties (Access = public)
        obj % Description
    end
    
    methods (Access = public)
        
    end
    
    methods (Access = private)
        
        function SetZoomMode(app,mode,event)
            disp(event);
            disp(event.Source);
            switch(mode)
                case 'zoom'
                    if event.Source == app.ZoomMenu
                        zoom(app.UIAxes,'on');
                        app.ZoomMenu.Checked = 'on';
                    end
                case 'pan'
                    if event.Source == app.PanMenu
                        pan(app.UIAxes,'on');
                        app.PanMenu.Checked = 'on';
                    end
                case 'none'
                    if event.Source == app.ResetZoomPanMenu
                        zoom(app.UIAxes,'off');
                        pan(app.UIAxes,'off');
                        app.UIAxes.XLimMode = 'auto';
                        app.UIAxes.YLimMode = 'auto';
                        app.ZoomMenu.Checked = 'off';
                        app.PanMenu.Checked = 'off';
                    end
                otherwise
                    error(100, 'This should not happen');
            end
            
        end
        
    end
    

    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            
            app.obj = PageScanParameterTuner(app);
            app.ColumnDistThresholdSpinner.Value = app.obj.ColumnDistThreshold;
            app.MaxCharHeightSpinner.Value = app.obj.MaxCharHeight;
            app.HorizontalDilationSpinner.Value = app.obj.HorizontalDilation;
            app.VerticalDilationSpinner.Value = app.obj.VerticalDilation;
            app.MaxCharWidthSpinner.Value = app.obj.MaxCharWidth;
            app.MinCharWidthSpinner.Value = app.obj.MinCharWidth;
            app.MinCharHeightSpinner.Value = app.obj.MinCharHeight;
            app.ShortHeightThresholdSpinner.Value = app.obj.ShortHeightThreshold;
            
            %Documented in 2019a manual. Is it working?
            %app.UIAxes.Toolbar.Visible = 'on';
        end

        % Menu selected function: LoadPageMenu
        function LoadPageMenuSelected(app, event)
            app.obj.LoadFcn(event);
        end

        % Menu selected function: ZoomMenu
        function ZoomMenuSelected(app, event)
            app.SetZoomMode('zoom',event);
        end

        % Menu selected function: PanMenu
        function PanMenuSelected(app, event)
            app.SetZoomMode('pan',event);
        end

        % Menu selected function: ResetZoomPanMenu
        function ResetZoomPanMenuSelected(app, event)
            app.SetZoomMode('none',event);
        end

        % Value changed function: ColumnDistThresholdSpinner
        function ColumnDistThresholdSpinnerValueChanged(app, event)
            value = app.ColumnDistThresholdSpinner.Value;
            app.obj.ColumnDistThreshold = value;
            app.obj.show_marked_page_img;
        end

        % Value changed function: MaxCharHeightSpinner
        function MaxCharHeightSpinnerValueChanged(app, event)
            value = app.MaxCharHeightSpinner.Value;
            app.obj.MaxCharHeight = value;
            app.obj.show_marked_page_img;
        end

        % Value changed function: HorizontalDilationSpinner
        function HorizontalDilationSpinnerValueChanged(app, event)
            value = app.HorizontalDilationSpinner.Value;
            app.obj.HorizontalDilation = value;
            app.obj.show_marked_page_img;
        end

        % Value changed function: VerticalDilationSpinner
        function VerticalDilationSpinnerValueChanged(app, event)
            value = app.VerticalDilationSpinner.Value;
            app.obj.VerticalDilation = value;
            app.obj.show_marked_page_img;
        end

        % Value changed function: MaxCharWidthSpinner
        function MaxCharWidthSpinnerValueChanged(app, event)
            value = app.MaxCharWidthSpinner.Value;
            app.obj.MaxCharWidth = value;
            app.obj.show_marked_page_img;
        end

        % Value changed function: MinCharWidthSpinner
        function MinCharWidthSpinnerValueChanged(app, event)
            value = app.MinCharWidthSpinner.Value;
            app.obj.MinCharWidth = value;
            app.obj.show_marked_page_img;
        end

        % Value changed function: MinCharHeightSpinner
        function MinCharHeightSpinnerValueChanged(app, event)
            value = app.MinCharHeightSpinner.Value;
            app.obj.MinCharHeight = value;
            app.obj.show_marked_page_img;
        end

        % Value changed function: ShortHeightThresholdSpinner
        function ShortHeightThresholdSpinnerValueChanged(app, event)
            value = app.ShortHeightThresholdSpinner.Value;
            app.obj.ShortHeightThreshold = value;
            app.obj.show_marked_page_img;
        end
    end

    % App initialization and construction
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.ParameterTunerUIFigure = uifigure;
            app.ParameterTunerUIFigure.Name = 'Parameter Tuner';
            app.FileMenu = uimenu(app.ParameterTunerUIFigure);
            app.FileMenu.Text = 'File';
            app.LoadPageMenu = uimenu(app.FileMenu);
            app.LoadPageMenu.MenuSelectedFcn = createCallbackFcn(app, @LoadPageMenuSelected, true);
            app.LoadPageMenu.Text = 'Load Page';
            app.EditMenu = uimenu(app.ParameterTunerUIFigure);
            app.EditMenu.Text = 'Edit';
            app.ZoomMenu = uimenu(app.EditMenu);
            app.ZoomMenu.MenuSelectedFcn = createCallbackFcn(app, @ZoomMenuSelected, true);
            app.ZoomMenu.Tooltip = {'Magnify a part of the page'};
            app.ZoomMenu.Text = 'Zoom';
            app.PanMenu = uimenu(app.EditMenu);
            app.PanMenu.MenuSelectedFcn = createCallbackFcn(app, @PanMenuSelected, true);
            app.PanMenu.Text = 'Pan';
            app.ResetZoomPanMenu = uimenu(app.EditMenu);
            app.ResetZoomPanMenu.MenuSelectedFcn = createCallbackFcn(app, @ResetZoomPanMenuSelected, true);
            app.ResetZoomPanMenu.Text = 'Reset Zoom/Pan';
            app.UIAxes = uiaxes(app.ParameterTunerUIFigure);
            title(app.UIAxes, 'Page Image')
            xlabel(app.UIAxes, 'X')
            ylabel(app.UIAxes, 'Y')
            app.ParametersPanel = uipanel(app.ParameterTunerUIFigure);
            app.ParametersPanel.Title = 'Parameters';
            app.MinCharWidthSpinnerLabel = uilabel(app.ParametersPanel);
            app.MinCharWidthSpinnerLabel.Text = 'MinCharWidth';
            app.MinCharWidthSpinner = uispinner(app.ParametersPanel);
            app.MinCharWidthSpinner.ValueChangedFcn = createCallbackFcn(app, @MinCharWidthSpinnerValueChanged, true);
            app.MaxCharWidthSpinnerLabel = uilabel(app.ParametersPanel);
            app.MaxCharWidthSpinnerLabel.Text = 'MaxCharWidth';
            app.MaxCharWidthSpinner = uispinner(app.ParametersPanel);
            app.MaxCharWidthSpinner.ValueChangedFcn = createCallbackFcn(app, @MaxCharWidthSpinnerValueChanged, true);
            app.MinCharHeightSpinnerLabel = uilabel(app.ParametersPanel);
            app.MinCharHeightSpinnerLabel.Text = 'MinCharHeight';
            app.MinCharHeightSpinner = uispinner(app.ParametersPanel);
            app.MinCharHeightSpinner.ValueChangedFcn = createCallbackFcn(app, @MinCharHeightSpinnerValueChanged, true);
            app.MaxCharHeightSpinnerLabel = uilabel(app.ParametersPanel);
            app.MaxCharHeightSpinnerLabel.Text = 'MaxCharHeight';
            app.MaxCharHeightSpinner = uispinner(app.ParametersPanel);
            app.MaxCharHeightSpinner.ValueChangedFcn = createCallbackFcn(app, @MaxCharHeightSpinnerValueChanged, true);
            app.ShortCharThesholdSpinnerLabel = uilabel(app.ParametersPanel);
            app.ShortCharThesholdSpinnerLabel.Text = 'ShortCharTheshold';
            app.ShortHeightThresholdSpinner = uispinner(app.ParametersPanel);
            app.ShortHeightThresholdSpinner.ValueChangedFcn = createCallbackFcn(app, @ShortHeightThresholdSpinnerValueChanged, true);
            app.ColumnDistThesholdSpinnerLabel = uilabel(app.ParametersPanel);
            app.ColumnDistThesholdSpinnerLabel.Text = 'ColumnDistTheshold';
            app.ColumnDistThresholdSpinner = uispinner(app.ParametersPanel);
            app.ColumnDistThresholdSpinner.ValueChangedFcn = createCallbackFcn(app, @ColumnDistThresholdSpinnerValueChanged, true);
            app.HorizontalDilationSpinnerLabel = uilabel(app.ParametersPanel);
            app.HorizontalDilationSpinnerLabel.Text = 'HorizontalDilation';
            app.HorizontalDilationSpinner = uispinner(app.ParametersPanel);
            app.HorizontalDilationSpinner.ValueChangedFcn = createCallbackFcn(app, @HorizontalDilationSpinnerValueChanged, true);
            app.VerticalDilationSpinnerLabel = uilabel(app.ParametersPanel);
            app.VerticalDilationSpinnerLabel.Text = 'VerticalDilation';
            app.VerticalDilationSpinner = uispinner(app.ParametersPanel);
            app.VerticalDilationSpinner.ValueChangedFcn = createCallbackFcn(app, @VerticalDilationSpinnerValueChanged, true);
        end
    end

    methods (Access = public)

        % Construct app
        function app = PageScanParameterTunerApp

            % Create and configure components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.ParameterTunerUIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.ParameterTunerUIFigure)
        end
    end
end