classdef LogisticRegressionApp < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        MNISTDigitLearnerUIFigure     matlab.ui.Figure
        FileMenu                      matlab.ui.container.Menu
        SaveMenu                      matlab.ui.container.Menu
        SaveAsMenu                    matlab.ui.container.Menu
        LoadMenu                      matlab.ui.container.Menu
        EditMenu                      matlab.ui.container.Menu
        HelpMenu                      matlab.ui.container.Menu
        ManualMenu                    matlab.ui.container.Menu
        AboutMenu                     matlab.ui.container.Menu
        TabGroup                      matlab.ui.container.TabGroup
        PickDigitsTab                 matlab.ui.container.Tab
        DigitViewerPanel              matlab.ui.container.Panel
        DigitPickerListBoxLabel       matlab.ui.control.Label
        DigitPickerListBox            matlab.ui.control.ListBox
        TrainTab                      matlab.ui.container.Tab
        UIAxes                        matlab.ui.control.UIAxes
        TrainFromScratchButton        matlab.ui.control.Button
        ContinuetrainingButton        matlab.ui.control.Button
        NumberOfErrorsEditFieldLabel  matlab.ui.control.Label
        NumberOfErrorsEditField       matlab.ui.control.NumericEditField
        LearningRateEditFieldLabel    matlab.ui.control.Label
        LearningRateEditField         matlab.ui.control.NumericEditField
        ConfusionTab                  matlab.ui.container.Tab
        ConfusionMatrixPanel          matlab.ui.container.Panel
        DigitTracingTab               matlab.ui.container.Tab
        SingleDigitPickerLabel        matlab.ui.control.Label
        SingleDigitPickerListBox      matlab.ui.control.ListBox
        PredictedDigitEditFieldLabel  matlab.ui.control.Label
        PredictedDigitEditField       matlab.ui.control.EditField
        UIAxes2                       matlab.ui.control.UIAxes
        HintintensitySliderLabel      matlab.ui.control.Label
        HintintensitySlider           matlab.ui.control.Slider
        UIAxes3                       matlab.ui.control.UIAxes
    end

    
    properties (Access = public)
        obj % Underlying object
    end
    
    properties (Access = public, Dependent)
        digits % Digits picked for training
        digit  % Digit picked for tracing
        hint_intensity %The intensity of hint image when tracing digits
    end
    
    methods
        function value = get.digits(app)
            value = cellfun(@str2double, app.DigitPickerListBox.Value);
        end
        function value = get.digit(app)
            value = str2double(app.SingleDigitPickerListBox.Value);
        end
        function value = get.hint_intensity(app)
            value = app.HintintensitySlider.Value / 100;
        end
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            app.obj = LogisticRegression(app);
            app.obj = app.obj.prepare_training_data;
            % Don't let standard behaviors, such as dragging
            disableDefaultInteractivity(app.UIAxes2);
            app.obj = app.obj.plot_mean_digit;
            app.UIAxes2.Tag = 'DrawingAxes';
            app.UIAxes3.Tag = 'ViewingAxes';
        end

        % Button pushed function: TrainFromScratchButton
        function TrainFromScratchButtonPushed(app, event)
            
            app.obj = app.obj.train;
        end

        % Value changed function: DigitPickerListBox
        function DigitPickerListBoxValueChanged(app, event)
            app.obj = app.obj.prepare_training_data;
        end

        % Button pushed function: ContinuetrainingButton
        function ContinuetrainingButtonPushed(app, event)
            app.obj = app.obj.train(true);
        end

        % Callback function
        function PlotConfusionButtonPushed(app, event)
            app.obj.plot_confusion;
        end

        % Value changed function: SingleDigitPickerListBox
        function SingleDigitPickerListBoxValueChanged(app, event)
            app.obj = app.obj.plot_mean_digit;
        end

        % Value changed function: HintintensitySlider
        function HintintensitySliderValueChanged(app, event)
            app.obj = app.obj.plot_mean_digit;
        end

        % Window button down function: MNISTDigitLearnerUIFigure
        function MNISTDigitLearnerUIFigureWindowButtonDown(app, event)
            app.obj = app.obj.WindowEventFcn(event);
        end

        % Window button motion function: MNISTDigitLearnerUIFigure
        function MNISTDigitLearnerUIFigureWindowButtonMotion(app, event)
            app.obj = app.obj.WindowEventFcn(event);
        end

        % Window button up function: MNISTDigitLearnerUIFigure
        function MNISTDigitLearnerUIFigureWindowButtonUp(app, event)
            app.obj = app.obj.WindowEventFcn(event);
        end

        % Menu selected function: SaveAsMenu
        function SaveAsMenuSelected(app, event)
            app.obj = app.obj.SaveAsFcn(event);
        end

        % Menu selected function: LoadMenu
        function LoadMenuSelected(app, event)
            app.obj = app.obj.LoadFcn(event);
        end

        % Menu selected function: SaveMenu
        function SaveMenuSelected(app, event)
            app.obj = app.obj.SaveFcn(event);
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.MNISTDigitLearnerUIFigure = uifigure('Visible', 'off');
            app.MNISTDigitLearnerUIFigure.Name = 'MNIST Digit Learner';
            app.MNISTDigitLearnerUIFigure.WindowButtonDownFcn = createCallbackFcn(app, @MNISTDigitLearnerUIFigureWindowButtonDown, true);
            app.MNISTDigitLearnerUIFigure.WindowButtonUpFcn = createCallbackFcn(app, @MNISTDigitLearnerUIFigureWindowButtonUp, true);
            app.MNISTDigitLearnerUIFigure.WindowButtonMotionFcn = createCallbackFcn(app, @MNISTDigitLearnerUIFigureWindowButtonMotion, true);
            app.FileMenu = uimenu(app.MNISTDigitLearnerUIFigure);
            app.FileMenu.Text = 'File';
            app.SaveMenu = uimenu(app.FileMenu);
            app.SaveMenu.MenuSelectedFcn = createCallbackFcn(app, @SaveMenuSelected, true);
            app.SaveMenu.Accelerator = 'S';
            app.SaveMenu.Text = 'Save';
            app.SaveAsMenu = uimenu(app.FileMenu);
            app.SaveAsMenu.MenuSelectedFcn = createCallbackFcn(app, @SaveAsMenuSelected, true);
            app.SaveAsMenu.Accelerator = 'T';
            app.SaveAsMenu.Text = 'Save As...';
            app.LoadMenu = uimenu(app.FileMenu);
            app.LoadMenu.MenuSelectedFcn = createCallbackFcn(app, @LoadMenuSelected, true);
            app.LoadMenu.Accelerator = 'L';
            app.LoadMenu.Text = 'Load';
            app.EditMenu = uimenu(app.MNISTDigitLearnerUIFigure);
            app.EditMenu.Text = 'Edit';
            app.HelpMenu = uimenu(app.MNISTDigitLearnerUIFigure);
            app.HelpMenu.Text = 'Help';
            app.ManualMenu = uimenu(app.HelpMenu);
            app.ManualMenu.Accelerator = 'M';
            app.ManualMenu.Text = 'Manual';
            app.AboutMenu = uimenu(app.HelpMenu);
            app.AboutMenu.Accelerator = 'A';
            app.AboutMenu.Text = 'About';
            app.TabGroup = uitabgroup(app.MNISTDigitLearnerUIFigure);
            app.PickDigitsTab = uitab(app.TabGroup);
            app.PickDigitsTab.Title = 'Pick Digits';
            app.DigitViewerPanel = uipanel(app.PickDigitsTab);
            app.DigitViewerPanel.Title = 'Digit Viewer';
            app.DigitPickerListBoxLabel = uilabel(app.PickDigitsTab);
            app.DigitPickerListBoxLabel.Text = 'Digit Picker';
            app.DigitPickerListBox = uilistbox(app.PickDigitsTab);
            app.DigitPickerListBox.Items = {'0', '1', '2', '3', '4', '5', '6', '7', '8', '9'};
            app.DigitPickerListBox.Multiselect = 'on';
            app.DigitPickerListBox.ValueChangedFcn = createCallbackFcn(app, @DigitPickerListBoxValueChanged, true);
            app.DigitPickerListBox.Tooltip = {'Pick your digits'};
            app.DigitPickerListBox.Value = {'0', '1', '2', '3'};
            app.TrainTab = uitab(app.TabGroup);
            app.TrainTab.Title = 'Train';
            app.UIAxes = uiaxes(app.TrainTab);
            title(app.UIAxes, 'Training Progress')
            xlabel(app.UIAxes, 'Epochs')
            ylabel(app.UIAxes, 'Loss (Cross-Entropy)')
            app.TrainFromScratchButton = uibutton(app.TrainTab, 'push');
            app.TrainFromScratchButton.ButtonPushedFcn = createCallbackFcn(app, @TrainFromScratchButtonPushed, true);
            app.TrainFromScratchButton.Text = 'Train From Scratch';
            app.ContinuetrainingButton = uibutton(app.TrainTab, 'push');
            app.ContinuetrainingButton.ButtonPushedFcn = createCallbackFcn(app, @ContinuetrainingButtonPushed, true);
            app.ContinuetrainingButton.Text = 'Continue training';
            app.NumberOfErrorsEditFieldLabel = uilabel(app.TrainTab);
            app.NumberOfErrorsEditFieldLabel.Text = 'Number Of Errors';
            app.NumberOfErrorsEditField = uieditfield(app.TrainTab, 'numeric');
            app.NumberOfErrorsEditField.Editable = 'off';
            app.NumberOfErrorsEditField.Value = 60000;
            app.LearningRateEditFieldLabel = uilabel(app.TrainTab);
            app.LearningRateEditFieldLabel.Text = 'LearningRate';
            app.LearningRateEditField = uieditfield(app.TrainTab, 'numeric');
            app.LearningRateEditField.Editable = 'off';
            app.ConfusionTab = uitab(app.TabGroup);
            app.ConfusionTab.Title = 'Confusion';
            app.ConfusionMatrixPanel = uipanel(app.ConfusionTab);
            app.ConfusionMatrixPanel.Title = 'Confusion Matrix';
            app.DigitTracingTab = uitab(app.TabGroup);
            app.DigitTracingTab.Title = 'Digit Tracing';
            app.SingleDigitPickerLabel = uilabel(app.DigitTracingTab);
            app.SingleDigitPickerLabel.Text = 'Digit Picker';
            app.SingleDigitPickerListBox = uilistbox(app.DigitTracingTab);
            app.SingleDigitPickerListBox.Items = {'0', '1', '2', '3', '4', '5', '6', '7', '8', '9'};
            app.SingleDigitPickerListBox.ValueChangedFcn = createCallbackFcn(app, @SingleDigitPickerListBoxValueChanged, true);
            app.SingleDigitPickerListBox.Tooltip = {'Pick single digit'};
            app.SingleDigitPickerListBox.Value = '0';
            app.PredictedDigitEditFieldLabel = uilabel(app.DigitTracingTab);
            app.PredictedDigitEditFieldLabel.Text = 'Predicted Digit';
            app.PredictedDigitEditField = uieditfield(app.DigitTracingTab, 'text');
            app.PredictedDigitEditField.Editable = 'off';
            app.UIAxes2 = uiaxes(app.DigitTracingTab);
            title(app.UIAxes2, 'Digit Tracing')
            xlabel(app.UIAxes2, 'X')
            ylabel(app.UIAxes2, 'Y')
            app.UIAxes2.XLim = [0 28];
            app.UIAxes2.YLim = [0 28];
            app.UIAxes2.YDir = 'reverse';
            app.UIAxes2.XAxisLocation = 'top';
            app.UIAxes2.XTick = [1 2 3 4 5 6 7 8 9 10 11 12 13 14 15 16 17 18 19 20 21 22 23 24 25 26 27 28];
            app.UIAxes2.XTickLabel = {'1'; '2'; '3'; '4'; '5'; '6'; '7'; '8'; '9'; '10'; '11'; '12'; '13'; '14'; '15'; '16'; '17'; '18'; '19'; '20'; '21'; '22'; '23'; '24'; '25'; '26'; '27'; '28'};
            app.UIAxes2.YTick = [1 2 3 4 5 6 7 8 9 10 11 12 13 14 15 16 17 18 19 20 21 22 23 24 25 26 27 28];
            app.UIAxes2.YTickLabel = {'1'; '2'; '3'; '4'; '5'; '6'; '7'; '8'; '9'; '10'; '11'; '12'; '13'; '14'; '15'; '16'; '17'; '18'; '19'; '20'; '21'; '22'; '23'; '24'; '25'; '26'; '27'; '28'};
            app.HintintensitySliderLabel = uilabel(app.DigitTracingTab);
            app.HintintensitySliderLabel.Text = 'Hint intensity';
            app.HintintensitySlider = uislider(app.DigitTracingTab);
            app.HintintensitySlider.ValueChangedFcn = createCallbackFcn(app, @HintintensitySliderValueChanged, true);
            app.HintintensitySlider.Value = 20;
            app.UIAxes3 = uiaxes(app.DigitTracingTab);
            title(app.UIAxes3, 'Drawing')
            ylabel(app.UIAxes3, {''; ''})
            app.UIAxes3.XTick = [];
            app.UIAxes3.YTick = [];
            % Show the figure after all components are created
            app.MNISTDigitLearnerUIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = LogisticRegressionApp

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.MNISTDigitLearnerUIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.MNISTDigitLearnerUIFigure)
        end
    end
end